classdef gaussian_2D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        Distribution1Panel           matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        xmeanEditFieldLabel          matlab.ui.control.Label
        xmeanEditField_1             matlab.ui.control.NumericEditField
        ymeanEditFieldLabel          matlab.ui.control.Label
        ymeanEditField_1             matlab.ui.control.NumericEditField
        xstddevEditFieldLabel        matlab.ui.control.Label
        xstddevEditField_1           matlab.ui.control.NumericEditField
        ystddevEditFieldLabel        matlab.ui.control.Label
        ystddevEditField_1           matlab.ui.control.NumericEditField
        correlationEditFieldLabel    matlab.ui.control.Label
        correlationEditField_1       matlab.ui.control.NumericEditField
        probabilityEditFieldLabel    matlab.ui.control.Label
        probabilityEditField_1       matlab.ui.control.NumericEditField
        costEditFieldLabel           matlab.ui.control.Label
        costEditField_1              matlab.ui.control.NumericEditField
        GeneratePlotButton           matlab.ui.control.Button
        Distribution2Panel           matlab.ui.container.Panel
        GridLayout2_2                matlab.ui.container.GridLayout
        xmeanEditField_2Label        matlab.ui.control.Label
        xmeanEditField_2             matlab.ui.control.NumericEditField
        ymeanEditField_2Label        matlab.ui.control.Label
        ymeanEditField_2             matlab.ui.control.NumericEditField
        xstddevEditField_2Label      matlab.ui.control.Label
        xstddevEditField_2           matlab.ui.control.NumericEditField
        ystddevEditField_2Label      matlab.ui.control.Label
        ystddevEditField_2           matlab.ui.control.NumericEditField
        correlationEditField_2Label  matlab.ui.control.Label
        correlationEditField_2       matlab.ui.control.NumericEditField
        probabilityEditField_2Label  matlab.ui.control.Label
        probabilityEditField_2       matlab.ui.control.NumericEditField
        costEditField_2Label         matlab.ui.control.Label
        costEditField_2              matlab.ui.control.NumericEditField
        PlotCharacteristicsPanel     matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        xminEditFieldLabel           matlab.ui.control.Label
        xminEditField                matlab.ui.control.NumericEditField
        xmaxEditFieldLabel           matlab.ui.control.Label
        xmaxEditField                matlab.ui.control.NumericEditField
        yminEditFieldLabel           matlab.ui.control.Label
        yminEditField                matlab.ui.control.NumericEditField
        ymaxEditFieldLabel           matlab.ui.control.Label
        ymaxEditField                matlab.ui.control.NumericEditField
        plotstyleDropDownLabel       matlab.ui.control.Label
        plotstyleDropDown            matlab.ui.control.DropDown
        BoundaryTextAreaLabel        matlab.ui.control.Label
        BoundaryTextArea             matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % distribution 1
        x1  % mean x
        y1  % mean y
        sx1 % standard deviation x
        sy1 % standard deviation y
        p1  % correlation coefficient
        P1  % a priori probability
        C1  % cost
        
        % distribution 2
        x2  % mean x
        y2  % mean y
        sx2 % standard deviation x
        sy2 % standard deviation y
        p2  % correlation coefficient
        P2  % a priori probability
        C2  % cost
        
        % plot characteristics
        x_min
        x_max
        y_min
        y_max
        plot_style
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % distribution 1 defaults
            app.x1 = 0;
            app.xmeanEditField_1.Value = app.x1;
            app.y1 = 0;
            app.ymeanEditField_1.Value = app.y1;
            app.sx1 = 2;
            app.xstddevEditField_1.Value = app.sx1;
            app.sy1 = 2;
            app.ystddevEditField_1.Value = app.sy1;
            app.p1 = 0;
            app.correlationEditField_1.Value = app.p1;
            app.P1 = 0.5;
            app.probabilityEditField_1.Value = app.P1;
            app.C1 = 1;
            app.costEditField_1.Value = app.C1;
            
            % distribution 2 defaults
            app.x2 = 4;
            app.xmeanEditField_2.Value = app.x2;
            app.y2 = 4;
            app.ymeanEditField_2.Value = app.y2;
            app.sx2 = 2;
            app.xstddevEditField_2.Value = app.sx2;
            app.sy2 = 2;
            app.ystddevEditField_2.Value = app.sy2;
            app.p2 = 0;
            app.correlationEditField_2.Value = app.p2;
            app.P2 = 0.5;
            app.probabilityEditField_2.Value = app.P2;
            app.C2 = 1;
            app.costEditField_2.Value = app.C2;
            
            % plot characteristics defaults
            app.x_min = -10;
            app.xminEditField.Value = app.x_min;
            app.x_max = 10;
            app.xmaxEditField.Value = app.x_max;
            app.y_min = -10;
            app.yminEditField.Value = app.y_min;
            app.y_max = 10;
            app.ymaxEditField.Value = app.y_max;
            app.plot_style = app.plotstyleDropDown.Items{1};
            app.plotstyleDropDown.Value = app.plot_style;
            
            % boundary box cannot be edited by user
            app.BoundaryTextArea.Editable = 0;
        end

        % Value changed function: xmeanEditField_1
        function xmeanEditField_1ValueChanged(app, event)
            app.x1 = app.xmeanEditField_1.Value;
        end

        % Value changed function: ymeanEditField_1
        function ymeanEditField_1ValueChanged(app, event)
            app.y1 = app.ymeanEditField_1.Value;
        end

        % Value changed function: xstddevEditField_1
        function xstddevEditField_1ValueChanged(app, event)
            app.sx1 = app.xstddevEditField_1.Value;
            assert(app.sx1 > 0, 'standard deviation must be greater than 0');
        end

        % Value changed function: ystddevEditField_1
        function ystddevEditField_1ValueChanged(app, event)
            app.sy1 = app.ystddevEditField_1.Value;
            assert(app.sy1 > 0, 'standard deviation must be > 0');
        end

        % Value changed function: correlationEditField_1
        function correlationEditField_1ValueChanged(app, event)
            app.p1 = app.correlationEditField_1.Value;
            assert(abs(app.p1) < 1, 'absolute value of correlation coefficient must be < 1');
        end

        % Value changed function: probabilityEditField_1
        function probabilityEditField_1ValueChanged(app, event)
            app.P1 = app.probabilityEditField_1.Value;
            app.P2 = 1 - app.P1;
            app.probabilityEditField_2.Value = app.P2;
            assert(app.P1 > 0, 'probability must be > 0');
        end

        % Value changed function: costEditField_1
        function costEditField_1ValueChanged(app, event)
            app.C1 = app.costEditField_1.Value;
            assert(app.C1 > 0, 'cost must be > 0');
        end

        % Value changed function: xmeanEditField_2
        function xmeanEditField_2ValueChanged(app, event)
            app.x2 = app.xmeanEditField_2.Value;
        end

        % Value changed function: ymeanEditField_2
        function ymeanEditField_2ValueChanged(app, event)
            app.y2 = app.ymeanEditField_2.Value;
        end

        % Value changed function: xstddevEditField_2
        function xstddevEditField_2ValueChanged(app, event)
            app.sx2 = app.xstddevEditField_2.Value;
            assert(app.sx2 > 0, 'standard deviation must be greater than 0');
        end

        % Value changed function: ystddevEditField_2
        function ystddevEditField_2ValueChanged(app, event)
            app.sy2 = app.ystddevEditField_2.Value;
            assert(app.sy2 > 0, 'standard deviation must be greater than 0');
        end

        % Value changed function: correlationEditField_2
        function correlationEditField_2ValueChanged(app, event)
            app.p2 = app.correlationEditField_2.Value;
            assert(abs(app.p2) < 1, 'absolute value of correlation coefficient must be < 1');
        end

        % Value changed function: probabilityEditField_2
        function probabilityEditField_2ValueChanged(app, event)
            app.P2 = app.probabilityEditField_2.Value;
            app.P1 = 1 - app.P2;
            app.probabilityEditField_1.Value = app.P1;
            assert(app.P2 > 0, 'probability must be > 0');
        end

        % Value changed function: costEditField_2
        function costEditField_2ValueChanged(app, event)
            app.C2 = app.costEditField_2.Value;
            assert(app.C2 > 0, 'cost must be > 0');
        end

        % Value changed function: xminEditField
        function xminEditFieldValueChanged(app, event)
            app.x_min = app.xminEditField.Value;
            assert(app.x_min < app.x_max, 'x_min must be < x_max');
        end

        % Value changed function: xmaxEditField
        function xmaxEditFieldValueChanged(app, event)
            app.x_max = app.xmaxEditField.Value;
        end

        % Value changed function: yminEditField
        function yminEditFieldValueChanged(app, event)
            app.y_min = app.yminEditField.Value;
            assert(app.y_min < app.y_max, 'y_min must be < y_max');
        end

        % Value changed function: ymaxEditField
        function ymaxEditFieldValueChanged(app, event)
            app.y_max = app.ymaxEditField.Value;
        end

        % Value changed function: plotstyleDropDown
        function plotstyleDropDownValueChanged(app, event)
            app.plot_style = app.plotstyleDropDown.Value;
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            syms x y real;
            
            % pdf of distribution 1
            M1 = app.sx1 * app.sy1 * sqrt(1 - app.p1 ^ 2);
            x_term1 = (x - app.x1) ^ 2 / app.sx1^2;
            y_term1 = (y - app.y1) ^ 2 / app.sy1^2;
            cross_term1 = 2 * app.p1 * (x - app.x1) * (y - app.y1) / (app.sx1 * app.sy1);
            pdf1 = 1 / (2 * pi * M1) * exp(-1 / (2 * (1 - app.p1 ^ 2)) * (x_term1 - cross_term1 + y_term1));
            
            % pdf of distribution 2
            M2 = app.sx2 * app.sy2 * sqrt(1 - app.p2 ^ 2);
            x_term2 = (x - app.x2) ^ 2 / app.sx2^2;
            y_term2 = (y - app.y2) ^ 2 / app.sy2^2;
            cross_term2 = 2 * app.p2 * (x - app.x2) * (y - app.y2) / (app.sx2 * app.sy2);
            pdf2 = 1 / (2 * pi * M2) * exp(-1 / (2 * (1 - app.p2 ^ 2)) * (x_term2 - cross_term2 + y_term2));           
            
            % weight the pdfs by their a priori probability and their cost
            pdf1_weighted = pdf1 * app.P1 * app.C1;
            pdf2_weighted = pdf2 * app.P2 * app.C2;
            
            % calculate boundary
            boundary_eqn = pdf1_weighted == pdf2_weighted;
            boundary = solve(boundary_eqn, y);
            boundary = simplify(boundary);
            if isempty(boundary)
                boundary = solve(boundary_eqn, x);
                app.BoundaryTextArea.Value = ['x = ', char(boundary)];
            else
                app.BoundaryTextArea.Value = ['y = ', char(boundary)];
            end
            
            % create grid of (x,y) points
            x_range = linspace(app.x_min, app.x_max);
            y_range = linspace(app.y_min, app.y_max);
            [x, y] = meshgrid(x_range, y_range);
            
            % evaluate the pdfs
            pdf1_weighted = double(subs(pdf1_weighted));
            pdf2_weighted = double(subs(pdf2_weighted));
            
            % plot the weighted pdfs
            pdf1_greater = pdf1_weighted > pdf2_weighted;
            colormap(app.UIAxes, [1 0 0; 0 1 0]);
            color_data = 2 - pdf1_greater;
            sc = surfc(app.UIAxes, x, y, pdf1_weighted, color_data);
            sc(2).ZLocation = 'zmax';
            sc(2).LineColor = 'w';
            hold(app.UIAxes, 'on');           
            sc = surfc(app.UIAxes, x, y, pdf2_weighted, color_data);
            sc(2).ZLocation = 'zmax';
            sc(2).LineColor = 'w';
            xlim(app.UIAxes, [app.x_min, app.x_max]);
            ylim(app.UIAxes, [app.y_min, app.y_max]);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            if strcmp(app.plot_style, '3D')
                title(app.UIAxes, '3D plot of weighted distributions');
            else
                title(app.UIAxes, '2D plot of weighted distributions');
                view(app.UIAxes, 0, 90);
            end
            hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Distribution1Panel = uipanel(app.GridLayout);
            app.Distribution1Panel.Title = 'Distribution 1';
            app.GridLayout2 = uigridlayout(app.Distribution1Panel);
            app.xmeanEditFieldLabel = uilabel(app.GridLayout2);
            app.xmeanEditFieldLabel.Text = 'x mean';
            app.xmeanEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.xmeanEditField_1.ValueChangedFcn = createCallbackFcn(app, @xmeanEditField_1ValueChanged, true);
            app.ymeanEditFieldLabel = uilabel(app.GridLayout2);
            app.ymeanEditFieldLabel.Text = 'y mean';
            app.ymeanEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.ymeanEditField_1.ValueChangedFcn = createCallbackFcn(app, @ymeanEditField_1ValueChanged, true);
            app.xstddevEditFieldLabel = uilabel(app.GridLayout2);
            app.xstddevEditFieldLabel.Text = 'x std dev';
            app.xstddevEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.xstddevEditField_1.ValueChangedFcn = createCallbackFcn(app, @xstddevEditField_1ValueChanged, true);
            app.ystddevEditFieldLabel = uilabel(app.GridLayout2);
            app.ystddevEditFieldLabel.Text = 'y std dev';
            app.ystddevEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.ystddevEditField_1.ValueChangedFcn = createCallbackFcn(app, @ystddevEditField_1ValueChanged, true);
            app.correlationEditFieldLabel = uilabel(app.GridLayout2);
            app.correlationEditFieldLabel.Text = 'correlation';
            app.correlationEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.correlationEditField_1.ValueChangedFcn = createCallbackFcn(app, @correlationEditField_1ValueChanged, true);
            app.probabilityEditFieldLabel = uilabel(app.GridLayout2);
            app.probabilityEditFieldLabel.Text = 'probability';
            app.probabilityEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.probabilityEditField_1.ValueChangedFcn = createCallbackFcn(app, @probabilityEditField_1ValueChanged, true);
            app.costEditFieldLabel = uilabel(app.GridLayout2);
            app.costEditFieldLabel.Text = 'cost';
            app.costEditField_1 = uieditfield(app.GridLayout2, 'numeric');
            app.costEditField_1.ValueChangedFcn = createCallbackFcn(app, @costEditField_1ValueChanged, true);
            app.GeneratePlotButton = uibutton(app.GridLayout, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = {'Generate Plot'; ''};
            app.Distribution2Panel = uipanel(app.GridLayout);
            app.Distribution2Panel.Title = 'Distribution 2';
            app.GridLayout2_2 = uigridlayout(app.Distribution2Panel);
            app.xmeanEditField_2Label = uilabel(app.GridLayout2_2);
            app.xmeanEditField_2Label.Text = 'x mean';
            app.xmeanEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.xmeanEditField_2.ValueChangedFcn = createCallbackFcn(app, @xmeanEditField_2ValueChanged, true);
            app.ymeanEditField_2Label = uilabel(app.GridLayout2_2);
            app.ymeanEditField_2Label.Text = 'y mean';
            app.ymeanEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.ymeanEditField_2.ValueChangedFcn = createCallbackFcn(app, @ymeanEditField_2ValueChanged, true);
            app.xstddevEditField_2Label = uilabel(app.GridLayout2_2);
            app.xstddevEditField_2Label.Text = 'x std dev';
            app.xstddevEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.xstddevEditField_2.ValueChangedFcn = createCallbackFcn(app, @xstddevEditField_2ValueChanged, true);
            app.ystddevEditField_2Label = uilabel(app.GridLayout2_2);
            app.ystddevEditField_2Label.Text = 'y std dev';
            app.ystddevEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.ystddevEditField_2.ValueChangedFcn = createCallbackFcn(app, @ystddevEditField_2ValueChanged, true);
            app.correlationEditField_2Label = uilabel(app.GridLayout2_2);
            app.correlationEditField_2Label.Text = 'correlation';
            app.correlationEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.correlationEditField_2.ValueChangedFcn = createCallbackFcn(app, @correlationEditField_2ValueChanged, true);
            app.probabilityEditField_2Label = uilabel(app.GridLayout2_2);
            app.probabilityEditField_2Label.Text = 'probability';
            app.probabilityEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.probabilityEditField_2.ValueChangedFcn = createCallbackFcn(app, @probabilityEditField_2ValueChanged, true);
            app.costEditField_2Label = uilabel(app.GridLayout2_2);
            app.costEditField_2Label.Text = 'cost';
            app.costEditField_2 = uieditfield(app.GridLayout2_2, 'numeric');
            app.costEditField_2.ValueChangedFcn = createCallbackFcn(app, @costEditField_2ValueChanged, true);
            app.PlotCharacteristicsPanel = uipanel(app.GridLayout);
            app.PlotCharacteristicsPanel.Title = 'Plot Characteristics';
            app.GridLayout3 = uigridlayout(app.PlotCharacteristicsPanel);
            app.xminEditFieldLabel = uilabel(app.GridLayout3);
            app.xminEditFieldLabel.Text = 'x min';
            app.xminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xminEditField.ValueChangedFcn = createCallbackFcn(app, @xminEditFieldValueChanged, true);
            app.xmaxEditFieldLabel = uilabel(app.GridLayout3);
            app.xmaxEditFieldLabel.Text = 'x max';
            app.xmaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxEditFieldValueChanged, true);
            app.yminEditFieldLabel = uilabel(app.GridLayout3);
            app.yminEditFieldLabel.Text = 'y min';
            app.yminEditField = uieditfield(app.GridLayout3, 'numeric');
            app.yminEditField.ValueChangedFcn = createCallbackFcn(app, @yminEditFieldValueChanged, true);
            app.ymaxEditFieldLabel = uilabel(app.GridLayout3);
            app.ymaxEditFieldLabel.Text = 'y max';
            app.ymaxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ymaxEditField.ValueChangedFcn = createCallbackFcn(app, @ymaxEditFieldValueChanged, true);
            app.plotstyleDropDownLabel = uilabel(app.GridLayout3);
            app.plotstyleDropDownLabel.Text = 'plot style';
            app.plotstyleDropDown = uidropdown(app.GridLayout3);
            app.plotstyleDropDown.Items = {'3D', '2D'};
            app.plotstyleDropDown.ValueChangedFcn = createCallbackFcn(app, @plotstyleDropDownValueChanged, true);
            app.plotstyleDropDown.Value = '3D';
            app.BoundaryTextAreaLabel = uilabel(app.GridLayout);
            app.BoundaryTextAreaLabel.Text = 'Boundary';
            app.BoundaryTextArea = uitextarea(app.GridLayout);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gaussian_2D

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end